classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ThermistancesLabel           matlab.ui.control.Label
        RgulateurLabel               matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        Denomerateur3EditField       matlab.ui.control.EditField
        Denomerateur3EditFieldLabel  matlab.ui.control.Label
        Numerateur3EditField         matlab.ui.control.EditField
        Numerateur3EditFieldLabel    matlab.ui.control.Label
        Denominateur2EditField       matlab.ui.control.EditField
        Denominateur2EditFieldLabel  matlab.ui.control.Label
        Numerateur2EditField         matlab.ui.control.EditField
        Numerateur2EditFieldLabel    matlab.ui.control.Label
        Denominateur1EditField       matlab.ui.control.EditField
        Denominateur1EditFieldLabel  matlab.ui.control.Label
        Numerateur1EditField         matlab.ui.control.EditField
        Numerateur1EditFieldLabel    matlab.ui.control.Label
        ValideretlancerButton        matlab.ui.control.Button
        IEditField                   matlab.ui.control.NumericEditField
        IEditFieldLabel              matlab.ui.control.Label
        PEditField                   matlab.ui.control.NumericEditField
        PEditFieldLabel              matlab.ui.control.Label
        ConsigneEditField            matlab.ui.control.NumericEditField
        ConsigneEditFieldLabel       matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Timer % Description
    end
    
    methods (Access = public)
        function updatePlot(app)
            f = waitbar(get_param('Design2simulink', 'SimulationTime')/2000, 'Simulation en cours');
            pause(1);
            while get_param('Design2simulink', 'SimulationTime') < 2000 && get_param('Design2simulink', 'SimulationTime') > 1
                f = waitbar(get_param('Design2simulink', 'SimulationTime')/2000, f, 'Simulation en cours');
                pause(1);
            end
           
            % Close dialog box
            close(f);
            simulationTime = 2000;%get_param('Design2simulink', 'SimulationTime');
            %get_param('Design2simulink',  '')
            signal_consigne = evalin('base', 'out.simout1');%simOut.get('signal_commande');
            %signal_consigne = get_param('Design2simulink/Scope'
            signal_sortie = evalin('base', 'out.simout');
            signal_t2 = evalin('base', 'out.simout2');
            signal_t3 = evalin('base', 'out.simout3');
            %signal_sortie = signal_sortie.signals.values;
            %signal_consigne = evalin('base', 'ScopeData');
            %signal_sortie = evalin('base', 'ScopeData');
            %disp(evalin('base', 'out').ScopeData(1));
            N = length(signal_sortie);
            temps = linspace(0, simulationTime, N);
            % Get the scope data from MATLAB workspace and plot it
            plot(app.UIAxes, temps, signal_consigne, 'b', 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');

            plot(app.UIAxes, temps, signal_sortie, 'r--', 'Linewidth', 1.5);
            plot(app.UIAxes, temps, signal_t2, 'g-.', 'LineWidth', 1.5);
            plot(app.UIAxes, temps, signal_t3, 'k:', 'LineWidth', 1.5)
            hold(app.UIAxes, 'off');


            legend(app.UIAxes, {'Consigne', 'T3', 'T1', 'T2'}, 'Location', 'best');
            
            title(app.UIAxes, 'Réponse du système');
            xlabel(app.UIAxes, 'Temps (s)');
            ylabel(app.UIAxes, 'Température');
            grid(app.UIAxes, 'on');
            
            
            % Créer une structure avec les paramètres
            %params = struct( ...
            %'P', P, ...
            %'I', I, ...
            %'Consigne', Consigne, ...
            %'TF1', struct('Numerator', num1, 'Denominator', den1), ...
            %'TF2', struct('Numerator', num2, 'Denominator', den2), ...
            %'TF3', struct('Numerator', num3, 'Denominator', den3), ...
            %'Horodatage', datetime("now", 'yyyy-mm-dd_HH-MM-SS') ...
            %);

            % Convertir en JSON
            %jsonStr = jsonencode(params);

            % Enregistrer dans un fichier
            %filename = ['parametres_simulation_' datetime("now", 'yyyymmdd_HHMMSS') '.json'];
            %fid = fopen(filename, 'w');
            %if fid ~= -1
             %   fwrite(fid, jsonStr, 'char');
              %  fclose(fid);
               % disp(['Paramètres sauvegardés dans : ' filename]);
            %else
             %   uialert(app.UIFigure, 'Erreur lors de l''écriture du fichier JSON', 'Erreur');
            %end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button down function: UIAxes, UIFigure
        function UIFigureButtonDown(app, event)
        
        %end

        %function ValideretlancerButtonPushed(app, event)
    
         %   end


        end

        % Button pushed function: ValideretlancerButton
        function ValideretlancerButtonPushed(app, event)

            % Lire les valeurs
                P = app.PEditField.Value;
                I = app.IEditField.Value;
                Consigne = app.ConsigneEditField.Value;

    % Lire les TF
                num1 = eval(app.Numerateur1EditField.Value);
                den1 = eval(app.Denominateur1EditField.Value);
                num2 = eval(app.Numerateur2EditField.Value);
                den2 = eval(app.Denominateur2EditField.Value);
                num3 = eval(app.Numerateur3EditField.Value);
                den3 = eval(app.Denomerateur3EditField.Value);

    % Vérification
                if isnan(P) || isnan(I) || isnan(Consigne)
                    uialert(app.UIFigure, 'Les valeurs entrées ne sont pas valides', 'Erreur');
                    return;
                end

    % Mettre à jour Simulink
                assignin('base', 'P', P);
                assignin('base', 'I', I);
                assignin('base', 'Consigne', Consigne);

                try
                    set_param("Design2simulink/PID Controller", 'P', num2str(P), 'I', num2str(I));
                    set_param('Design2simulink/Step', 'After', num2str(Consigne));
                    %uialert(app.UIFigure, ['hello : ' [num2str(num1)]], 'Erreur');
                    set_param('Design2simulink/Procédé/Plaque/T1', 'Numerator', mat2str(num1), 'Denominator', mat2str(den1));
                    set_param('Design2simulink/Procédé/Plaque/T2', 'Numerator', mat2str(num2), 'Denominator', mat2str(den2));
                    set_param('Design2simulink/Procédé/Plaque/T3', 'Numerator', mat2str(num3), 'Denominator', mat2str(den3));

                catch ME

                    uialert(app.UIFigure, ['Erreur dans Simulink : ' ME.message], 'Erreur');
                    return;
                end

    % Lancer la simulation
               
                set_param('Design2simulink', 'SimulationCommand', 'start');
                updatePlot(app);
                %app.Timer = timer('ExecutionMode', 'fixedRate', 'Period', 1, 'TimerFcn', @(~,~)updatePlot(app));
                %start(app.Timer);
               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Réponse du système')
            xlabel(app.UIAxes, 'Temps (s)')
            ylabel(app.UIAxes, 'Température (°C)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.ConsigneEditFieldLabel = uilabel(app.UIFigure);
            app.ConsigneEditFieldLabel.Text = 'Consigne';
            app.ConsigneEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConsigneEditField.Value = 25;
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditField.Value = 0.5;
            app.IEditFieldLabel = uilabel(app.UIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.UIFigure, 'numeric');
            app.IEditField.Value = 0.005;
            app.ValideretlancerButton = uibutton(app.UIFigure, 'push');
            app.ValideretlancerButton.ButtonPushedFcn = createCallbackFcn(app, @ValideretlancerButtonPushed, true);
            app.ValideretlancerButton.Text = 'Valider et lancer';
            app.Numerateur1EditFieldLabel = uilabel(app.UIFigure);
            app.Numerateur1EditFieldLabel.Text = 'Numerateur 1';
            app.Numerateur1EditField = uieditfield(app.UIFigure, 'text');
            app.Numerateur1EditField.Value = '[8.07]';
            app.Denominateur1EditFieldLabel = uilabel(app.UIFigure);
            app.Denominateur1EditFieldLabel.Text = 'Denominateur 1';
            app.Denominateur1EditField = uieditfield(app.UIFigure, 'text');
            app.Denominateur1EditField.Value = '[90.09 1]';
            app.Numerateur2EditFieldLabel = uilabel(app.UIFigure);
            app.Numerateur2EditFieldLabel.Text = 'Numerateur 2';
            app.Numerateur2EditField = uieditfield(app.UIFigure, 'text');
            app.Numerateur2EditField.Value = '[0.789]';
            app.Denominateur2EditFieldLabel = uilabel(app.UIFigure);
            app.Denominateur2EditFieldLabel.Text = 'Denominateur 2';
            app.Denominateur2EditField = uieditfield(app.UIFigure, 'text');
            app.Denominateur2EditField.Value = '[40.49 1]';
            app.Numerateur3EditFieldLabel = uilabel(app.UIFigure);
            app.Numerateur3EditFieldLabel.Text = 'Numerateur 3';
            app.Numerateur3EditField = uieditfield(app.UIFigure, 'text');
            app.Numerateur3EditField.Value = '[0.921]';
            app.Denomerateur3EditFieldLabel = uilabel(app.UIFigure);
            app.Denomerateur3EditFieldLabel.Text = 'Denomerateur 3';
            app.Denomerateur3EditField = uieditfield(app.UIFigure, 'text');
            app.Denomerateur3EditField.Value = '[20.28 1]';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'bab.jpeg');
            app.RgulateurLabel = uilabel(app.UIFigure);
            app.RgulateurLabel.Text = 'Régulateur';
            app.ThermistancesLabel = uilabel(app.UIFigure);
            app.ThermistancesLabel.Text = 'Thermistances :';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end