classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ButtonGroup_4                   matlab.ui.container.ButtonGroup
        Panel_2                         matlab.ui.container.Panel
        DcalT3EditField                 matlab.ui.control.NumericEditField
        DcalT3EditFieldLabel            matlab.ui.control.Label
        GainT3EditField                 matlab.ui.control.NumericEditField
        GainT3EditFieldLabel            matlab.ui.control.Label
        DcalT2EditField                 matlab.ui.control.NumericEditField
        DcalT2EditFieldLabel            matlab.ui.control.Label
        GainT2EditField                 matlab.ui.control.NumericEditField
        GainT2EditFieldLabel            matlab.ui.control.Label
        DcalT1EditField                 matlab.ui.control.NumericEditField
        DcalT1EditFieldLabel            matlab.ui.control.Label
        GainT1EditField                 matlab.ui.control.NumericEditField
        GainT1EditFieldLabel            matlab.ui.control.Label
        lectroniqueLabel                matlab.ui.control.Label
        chantillonnageHzEditField       matlab.ui.control.NumericEditField
        chantillonnageHzEditFieldLabel  matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        PerturbationLabel               matlab.ui.control.Label
        FinsEditField                   matlab.ui.control.NumericEditField
        FinsEditFieldLabel              matlab.ui.control.Label
        PuissanceWEditField             matlab.ui.control.NumericEditField
        PuissanceWEditFieldLabel        matlab.ui.control.Label
        DbutsEditField                  matlab.ui.control.NumericEditField
        DbutsEditFieldLabel             matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        RgulateurLabel                  matlab.ui.control.Label
        IEditField                      matlab.ui.control.NumericEditField
        IEditFieldLabel                 matlab.ui.control.Label
        PEditField                      matlab.ui.control.NumericEditField
        PEditFieldLabel                 matlab.ui.control.Label
        ConsigneCEditField              matlab.ui.control.NumericEditField
        ConsigneCLabel                  matlab.ui.control.Label
        PuissanceEditField              matlab.ui.control.NumericEditField
        PuissanceEditFieldLabel         matlab.ui.control.Label
        RguldbutsEditField              matlab.ui.control.NumericEditField
        RguldbutsEditFieldLabel         matlab.ui.control.Label
        BoucleouverteButton             matlab.ui.control.RadioButton
        BouclefermeButton               matlab.ui.control.RadioButton
        TabGroup2                       matlab.ui.container.TabGroup
        TempraturesTab                  matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        UT1Tab                          matlab.ui.container.Tab
        T1_Dnum                         matlab.ui.control.EditField
        DnominateurEditField_2Label     matlab.ui.control.Label
        T1_Num                          matlab.ui.control.EditField
        NumrateurEditField_2Label       matlab.ui.control.Label
        T1T2Tab                         matlab.ui.container.Tab
        T2_Dnum                         matlab.ui.control.EditField
        DnominateurEditFieldLabel       matlab.ui.control.Label
        T2_Num                          matlab.ui.control.EditField
        NumrateurEditFieldLabel         matlab.ui.control.Label
        T2T3Tab                         matlab.ui.container.Tab
        T3_Dnum                         matlab.ui.control.EditField
        DnominateurEditField_3Label     matlab.ui.control.Label
        T3_Num                          matlab.ui.control.EditField
        NumrateurEditField_3Label       matlab.ui.control.Label
        graph_temp                      matlab.ui.control.UIAxes
        PerturbationTab                 matlab.ui.container.Tab
        TabGroup_2                      matlab.ui.container.TabGroup
        PT1Tab                          matlab.ui.container.Tab
        Dnom_P1                         matlab.ui.control.EditField
        DnominateurEditField_4Label     matlab.ui.control.Label
        Num_P1                          matlab.ui.control.EditField
        NumrateurEditField_4Label       matlab.ui.control.Label
        PT2Tab                          matlab.ui.container.Tab
        Dnom_P2                         matlab.ui.control.EditField
        DnominateurEditField_5Label     matlab.ui.control.Label
        Num_P2                          matlab.ui.control.EditField
        NumrateurEditField_5Label       matlab.ui.control.Label
        PT3Tab                          matlab.ui.container.Tab
        Dnom_P3                         matlab.ui.control.EditField
        DnominateurEditField_6Label     matlab.ui.control.Label
        Num_P3                          matlab.ui.control.EditField
        NumrateurEditField_6Label       matlab.ui.control.Label
        graph_perturbation              matlab.ui.control.UIAxes
        RsistancesTab                   matlab.ui.container.Tab
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        R25EditField                    matlab.ui.control.NumericEditField
        R25EditFieldLabel               matlab.ui.control.Label
        DEditField                      matlab.ui.control.NumericEditField
        DEditFieldLabel                 matlab.ui.control.Label
        ThermistancesLabel_2            matlab.ui.control.Label
        CEditField                      matlab.ui.control.NumericEditField
        CEditFieldLabel                 matlab.ui.control.Label
        AEditField                      matlab.ui.control.NumericEditField
        AEditFieldLabel                 matlab.ui.control.Label
        BEditField                      matlab.ui.control.NumericEditField
        BEditFieldLabel                 matlab.ui.control.Label
        graph_res                       matlab.ui.control.UIAxes
        TensionsTab                     matlab.ui.container.Tab
        graph_tens                      matlab.ui.control.UIAxes
        RgulateurTab                    matlab.ui.container.Tab
        ADCbitsEditField_2              matlab.ui.control.NumericEditField
        ADCbitsEditField_2Label         matlab.ui.control.Label
        graph_reg                       matlab.ui.control.UIAxes
        PWMTab                          matlab.ui.container.Tab
        DACbitsEditField_2              matlab.ui.control.NumericEditField
        DACbitsEditField_2Label         matlab.ui.control.Label
        graph_entre_pwm                 matlab.ui.control.UIAxes
        graph_redresseur_pwm            matlab.ui.control.UIAxes
        AmpliTab                        matlab.ui.container.Tab
        graph_ampli                     matlab.ui.control.UIAxes
        IdentificationTab               matlab.ui.container.Tab
        ChoisirunfichierdchelonButton   matlab.ui.control.Button
        graph_id_entre                  matlab.ui.control.UIAxes
        graph_identification            matlab.ui.control.UIAxes
        TempssimsEditField              matlab.ui.control.NumericEditField
        TempssimsEditFieldLabel         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ValideretlancerButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        Timer % Description
    end
    
    methods (Access = public)
        function startSimulation(app, tempsSimulation)
            f = waitbar(get_param('Design2simulink', 'SimulationTime')/tempsSimulation, 'Simulation en cours');
            %f.CloseRequestFcn = @(src,event)my_closereq(src);

            %function my_closereq(fig)
               % delete(f);
              %  f = waitbar(get_param('Design2simulink', 'SimulationTime')/tempsSimulation, 'Simulation en cours');
             %   return
            %end

            set_param('Design2simulink', 'SimulationCommand', 'start');
            pause(1);
            while get_param('Design2simulink', 'SimulationTime') < tempsSimulation && get_param('Design2simulink', 'SimulationTime') > 1
                if ishandle(f)
                    f = waitbar(get_param('Design2simulink', 'SimulationTime')/tempsSimulation, f, 'Simulation en cours');
                else
                    f = waitbar(get_param('Design2simulink', 'SimulationTime')/tempsSimulation, 'Simulation en cours');                    
                end
                pause(1);
            end
           
            signal_consigne = evalin('base', 'out.consigne');%simOut.get('signal_commande');
            signal_t1 = evalin('base', 'out.T_1');
            signal_t2 = evalin('base', 'out.T_2');
            signal_sortie = evalin('base', 'out.T_3');
            signal_t3_est = evalin('base', 'out.T_3_est');
            N = length(signal_sortie);
            temps = linspace(0, tempsSimulation, N);
            
            
            plot(app.graph_temp, temps, signal_t1, '-.', 'LineWidth', 1.5);
            hold(app.graph_temp, 'on');
            plot(app.graph_temp, temps, signal_t2, ':', 'LineWidth', 1.5);
            plot(app.graph_temp, temps, signal_sortie, '--', 'Linewidth', 1.5);
            plot(app.graph_temp, temps, signal_t3_est, '.', 'LineWidth', 1.5);
            plot(app.graph_temp, temps, signal_consigne, 'b', 'LineWidth', 1.5);


            legend(app.graph_temp, {'T1', 'T2', 'T3', 'T3 est.', 'Consigne'}, 'Location', 'best');
            hold(app.graph_temp, 'off');
            
            grid(app.graph_res, 'on');
            
            plot(app.graph_res, temps, temp_to_res(app, signal_t1), '-.', 'LineWidth', 1.5);
            hold(app.graph_res, 'on');
            plot(app.graph_res, temps, temp_to_res(app, signal_t2), ':', 'LineWidth', 1.5);
            plot(app.graph_res, temps, temp_to_res(app, signal_sortie), '--', 'Linewidth', 1.5);


            legend(app.graph_res, {'R1', 'R2', 'R3'}, 'Location', 'best');
            
            title(app.graph_res, 'Réponse du système');
            xlabel(app.graph_res, 'Temps (s)');
            ylabel(app.graph_res, 'Résistance (Ohms)');
            hold(app.graph_res, 'off');


            r_div = 10000;
            grid(app.graph_tens, 'on');
            plot(app.graph_tens, temps, res_to_volt(app, temp_to_res(app, signal_t1), r_div), '-.', 'Linewidth', 1.5);
            hold(app.graph_tens, 'on');
            plot(app.graph_tens, temps, res_to_volt(app, temp_to_res(app, signal_t2), r_div), ':', 'LineWidth', 1.5);
            plot(app.graph_tens, temps, res_to_volt(app, temp_to_res(app, signal_sortie), r_div), '--', 'LineWidth', 1.5)


            legend(app.graph_tens, {'Vt1', 'Vt2', 'Vt3'}, 'Location', 'best');
            
            title(app.graph_tens, 'Réponse du système');
            xlabel(app.graph_tens, 'Temps (s)');
            ylabel(app.graph_tens, 'Tension (V)');
            hold(app.graph_tens, 'off');

            sortie_adc = evalin('base', 'out.Sortie_ADC');
            entree_reg = evalin('base', 'out.Entree_Reg');
            
            plot(app.graph_reg, temps, sortie_adc, 'r-.', 'LineWidth', 1.5);
            
            hold(app.graph_reg, 'on');
            plot(app.graph_reg, temps, entree_reg, 'b', 'LineWidth', 1.5)
            hold(app.graph_reg, 'off');


            legend(app.graph_reg, {"Sortie de l'ADC", 'Entrée du régulateur'}, 'Location', 'best');
            
            grid(app.graph_reg, 'on');


            sortie_dac = evalin('base', 'out.Sortie_DAC');
            sortie_pwm = evalin('base', 'out.Sortie_PWM');
            
            plot(app.graph_entre_pwm, temps, sortie_dac, 'r-.', 'LineWidth', 1.5);
            
            plot(app.graph_redresseur_pwm, temps, sortie_pwm, 'b', 'LineWidth', 1.5);

            sortie_source = evalin('base', 'out.Sortie_Source');
            
            plot(app.graph_ampli, temps, sortie_pwm, 'LineWidth', 1.5);
            hold(app.graph_ampli, 'on');
            plot(app.graph_ampli, temps, sortie_source, 'LineWidth', 1.5);
            grid(app.graph_ampli, 'off');

            legend(app.graph_ampli, {'Entrée de la source', 'Sortie de la source'}, 'Location', 'best');
            
            perturbation = evalin('base', 'out.Perturbation');

            plot(app.graph_perturbation, temps, perturbation);
            

            close(f);
            
            
            % Créer une structure avec les paramètres
            %params = struct( ...
            %'P', P, ...
            %'I', I, ...
            %'Consigne', Consigne, ...
            %'TF1', struct('Numerator', num1, 'Denominator', den1), ...
            %'TF2', struct('Numerator', num2, 'Denominator', den2), ...
            %'TF3', struct('Numerator', num3, 'Denominator', den3), ...
            %'Horodatage', datetime("now", 'yyyy-mm-dd_HH-MM-SS') ...
            %);

            % Convertir en JSON
            %jsonStr = jsonencode(params);

            % Enregistrer dans un fichier
            %filename = ['parametres_simulation_' datetime("now", 'yyyymmdd_HHMMSS') '.json'];
            %fid = fopen(filename, 'w');
            %if fid ~= -1
             %   fwrite(fid, jsonStr, 'char');
              %  fclose(fid);
               % disp(['Paramètres sauvegardés dans : ' filename]);
            %else
             %   uialert(app.UIFigure, 'Erreur lors de l''écriture du fichier JSON', 'Erreur');
            %end
        end
    end
    
    methods (Access = private)


        function result = temp_to_res(app, temp)
            A = app.AEditField.Value;
            B = app.BEditField.Value;
            C = app.CEditField.Value;
            D = app.DEditField.Value;
            R_25 = app.R25EditField.Value;
            
            for i = 1:numel(temp)           
  
                computed_value = R_25 * exp(A + (B / (temp(i) + 275.15)) + (C / ((temp(i) + 275.15)^2)) + (D / ((temp(i) + 275.15)^3)));
                result(i) = computed_value;

            end      
            
            %disp(result);
        end
        
        function result = res_to_volt(app, res, r_div)
            for i = 1:numel(res)           
  
                volt = (res(i)/(res(i)+r_div))*5;
                result(i) = volt;

            end      
            
        end
    
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin('base', 'switchBoucle', [0 1]);
            assignin('base', 'bits_DAC', [0 10]);
            assignin('base', 'bits_ADC', [0 12]);
            assignin('base', 'Perturbation', 1);
            assignin('base', 'echantillonnage', 1);
            assignin('base', 'percentPower', [0 100]);
            assignin('base', 'gain_t1', app.GainT1EditField.Value);
            assignin('base', 'gain_t2', app.GainT2EditField.Value);
            assignin('base', 'gain_t3', app.GainT3EditField.Value);

            assignin('base', 'decal_t1', app.DcalT1EditField.Value);
            assignin('base', 'decal_t2', app.DcalT2EditField.Value);
            assignin('base', 'decal_t3', app.DcalT3EditField.Value);
            app.RguldbutsEditField.Enable = 'on';
            app.ConsigneCEditField.Enable = 'on';
            app.PEditField.Enable = 'on';
            app.IEditField.Enable = 'on';
            app.PuissanceEditField.Enable = 'off';
        end

        % Button down function: UIFigure, graph_temp
        function UIFigureButtonDown(app, event)
        
        %end

        %function ValideretlancerButtonPushed(app, event)
    
         %   end


        end

        % Button pushed function: ValideretlancerButton
        function ValideretlancerButtonPushed(app, event)

            % Lire les valeurs
                P = app.PEditField.Value;
                I = app.IEditField.Value;
                Consigne = app.ConsigneCEditField.Value;
                tempsSimulation = app.TempssimsEditField.Value;

    % Lire les TF
                numT1 = eval(app.T1_Num.Value);
                denT1 = eval(app.T1_Dnum.Value);
                numT2 = eval(app.T2_Num.Value);
                denT2 = eval(app.T2_Dnum.Value);
                numT3 = eval(app.T3_Num.Value);
                denT3 = eval(app.T3_Dnum.Value);


                numP1 = eval(app.Num_P1.Value);
                denP1 = eval(app.Dnom_P1.Value);
                numP2 = eval(app.Num_P2.Value);
                denP2 = eval(app.Dnom_P2.Value);
                numP3 = eval(app.Num_P3.Value);
                denP3 = eval(app.Dnom_P3.Value);

    % Vérification
                if isnan(P) || isnan(I) || isnan(Consigne) || isnan(tempsSimulation)
                    uialert(app.UIFigure, 'Les valeurs entrées ne sont pas valides', 'Erreur');
                    return;
                end

    % Mettre à jour Simulink
                assignin('base', 'P', P);
                assignin('base', 'I', I);
                assignin('base', 'Consigne', Consigne);
                set_param('Design2simulink', 'StopTime', num2str(tempsSimulation));

                assignin('base', 'percentPower', [0 app.PuissanceEditField.Value]);

                %assignin('base', 'Perturbation', app.PuissanceWEditField);
                set_param('Design2simulink/Procédé/Plaque/Perturbation', 'After', num2str(app.PuissanceWEditField.Value));
                set_param('Design2simulink/Procédé/Plaque/Perturbation', 'Time', num2str(app.DbutsEditField.Value));

                try
                    set_param('Design2simulink/Régulateur/PID Controller', 'P', num2str(P), 'I', num2str(I));
                    set_param('Design2simulink/Step', 'After', num2str(Consigne));
                    set_param('Design2simulink/Step', 'Time', num2str(app.RguldbutsEditField.Value));
                    %uialert(app.UIFigure, ['hello : ' [num2str(num1)]], 'Erreur');
                    set_param('Design2simulink/Procédé/Plaque/T1', 'Numerator', mat2str(numT1), 'Denominator', mat2str(denT1));
                    set_param('Design2simulink/Procédé/Plaque/T2', 'Numerator', mat2str(numT2), 'Denominator', mat2str(denT2));
                    set_param('Design2simulink/Procédé/Plaque/T3', 'Numerator', mat2str(numT3), 'Denominator', mat2str(denT3));

                    set_param('Design2simulink/Procédé/Plaque/Application de la perturbation/P1', 'Numerator', mat2str(numP1), 'Denominator', mat2str(denP1));
                    set_param('Design2simulink/Procédé/Plaque/Application de la perturbation/P2', 'Numerator', mat2str(numP2), 'Denominator', mat2str(denP2));
                    set_param('Design2simulink/Procédé/Plaque/Application de la perturbation/P3', 'Numerator', mat2str(numP3), 'Denominator', mat2str(denP3));


                    assignin('base', 'echantillonnage', app.chantillonnageHzEditField.Value);
                    assignin('base', 'gain_t1', app.GainT1EditField.Value);
                    assignin('base', 'gain_t2', app.GainT2EditField.Value);
                    assignin('base', 'gain_t3', app.GainT3EditField.Value);

                    assignin('base', 'decal_t1', app.DcalT1EditField.Value);
                    assignin('base', 'decal_t2', app.DcalT2EditField.Value);
                    assignin('base', 'decal_t3', app.DcalT3EditField.Value);

                catch ME

                    uialert(app.UIFigure, ['Erreur dans Simulink : ' ME.message], 'Erreur');
                    return;
                end

                % Lancer la simulation
                startSimulation(app, tempsSimulation);
                %app.Timer = timer('ExecutionMode', 'fixedRate', 'Period', 1, 'TimerFcn', @(~,~)updatePlot(app));
                %start(app.Timer);
               
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.BoucleouverteButton
                assignin('base', 'switchBoucle', [0 -1]);
                app.RguldbutsEditField.Enable = 'off';
                app.ConsigneCEditField.Enable = 'off';
                app.PEditField.Enable = 'off';
                app.IEditField.Enable = 'off';
                app.PuissanceEditField.Enable = 'on';
            else
                assignin('base', 'switchBoucle', [0 1]);
                app.RguldbutsEditField.Enable = 'on';
                app.ConsigneCEditField.Enable = 'on';
                app.PEditField.Enable = 'on';
                app.IEditField.Enable = 'on';
                app.PuissanceEditField.Enable = 'off';
            end
        end

        % Value changed function: DACbitsEditField_2
        function DACbitsEditField_2ValueChanged(app, event)
            assignin('base', 'bits_DAC', [0 app.DACbitsEditField_2.Value]);
        end

        % Value changed function: ADCbitsEditField_2
        function ADCbitsEditField_2ValueChanged(app, event)
            assignin('base', 'bits_ADC', [0 app.ADCbitsEditField_2.Value]);
        end

        % Button pushed function: ChoisirunfichierdchelonButton
        function ChoisirunfichierdchelonButtonPushed(app, event)
            [file, path] = uigetfile({'*.txt;*.csv', 'Text and CSV Files (*.txt, *.csv)'}, ...
                             'Select a File');

            if file ~= 0
                data = importdata(fullfile(path, file));
                %disp(data);
                temps = data(:, 1);
                commande = data(:, 2);% - data(1, 2);
                perturbation = data(:, 3);
                t1 = data(:, 4);
                t2 = data(:, 5);
                t3 = data(:, 6);

                plot(app.graph_id_entre, temps, commande, 'LineWidth', 1.5);
                hold(app.graph_id_entre, 'on');
                plot(app.graph_id_entre, temps, perturbation, 'LineWidth', 1.5);
                hold(app.graph_id_entre, 'off');
                legend(app.graph_id_entre, {'Commande (A)', 'Perturbation (W)'}, 'Location', 'best');

                plot(app.graph_identification, temps, t1, 'LineWidth', 1.5)
                hold(app.graph_identification, 'on');
                plot(app.graph_identification, temps, t2, '.', 'Linewidth', 1.5);
                plot(app.graph_identification, temps, t3, '-.', 'LineWidth', 1.5);
                legend(app.graph_identification, {'T1', 'T2', 'T3'}, 'Location', 'best');
                hold(app.graph_identification, 'off');

                u = -(data(:, 2) - data(1, 2)); % Soustrait le point d'operation
                y = data(:, 4) - data(1, 4);

                t1_dat = iddata(y, u, 1);

                [f_t_t1, info] = tfest(t1_dat, 1, 0, 0);

                %compare(t1_dat, f_t_t1);

                assignin('base', 'f_t_t1', f_t_t1);

                app.T1_Num.Value = mat2str(f_t_t1.Numerator);
                app.T1_Dnum.Value = mat2str(f_t_t1.Denominator);

                u = y;
                y = data(:, 5) - data(1, 5);

                t2_dat = iddata(y, u, 1);

                [f_t_t2, info] = tfest(t2_dat, 1, 0, 0);

                %compare(t2_dat, f_t_t2);

                assignin('base', 'f_t_t2', f_t_t2);

                app.T2_Num.Value = mat2str(f_t_t2.Numerator);
                app.T2_Dnum.Value = mat2str(f_t_t2.Denominator);

                u = y;
                y = data(:, 6) - data(1, 6);

                t3_dat = iddata(y, u, 1);

                [f_t_t3, info] = tfest(t3_dat, 1, 0, 0);

                %compare(t3_dat, f_t_t3);

                assignin('base', 'f_t_t3', f_t_t3);

                app.T3_Num.Value = mat2str(f_t_t3.Numerator);
                app.T3_Dnum.Value = mat2str(f_t_t3.Denominator);
            else
                disp('File selection was canceled');
            end
        end

        % Callback function: not associated with a component
        function ButtonGroup_5SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_5.SelectedObject;
            % Check the text of the selected radio button
            if strcmp(selectedButton.Text, 'Température')
                % Show the Températures panel and hide the Gain/décalage panel
                app.Panel.Visible = 'on';
                app.Panel_2.Visible = 'off';
            else  % Assuming the only alternative is 'Gain/décalage'
                % Hide the Températures panel and show the Gain/décalage panel
                app.Panel.Visible = 'off';
                app.Panel_2.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.ValideretlancerButton = uibutton(app.UIFigure, 'push');
            app.ValideretlancerButton.ButtonPushedFcn = createCallbackFcn(app, @ValideretlancerButtonPushed, true);
            app.ValideretlancerButton.Text = 'Valider et lancer';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'bab.jpeg');
            app.TempssimsEditFieldLabel = uilabel(app.UIFigure);
            app.TempssimsEditFieldLabel.Text = 'Temps sim (s)';
            app.TempssimsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TempssimsEditField.Value = 2000;
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TempraturesTab = uitab(app.TabGroup2);
            app.TempraturesTab.Title = 'Températures';
            app.graph_temp = uiaxes(app.TempraturesTab);
            title(app.graph_temp, 'Réponse du système')
            xlabel(app.graph_temp, 'Temps (s)')
            ylabel(app.graph_temp, 'Température (°C)')
            zlabel(app.graph_temp, 'Z')
            app.graph_temp.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.TabGroup = uitabgroup(app.TempraturesTab);
            app.UT1Tab = uitab(app.TabGroup);
            app.UT1Tab.Title = 'U->T1';
            app.NumrateurEditField_2Label = uilabel(app.UT1Tab);
            app.NumrateurEditField_2Label.Text = 'Numérateur ';
            app.T1_Num = uieditfield(app.UT1Tab, 'text');
            app.T1_Num.Value = '[-8.07]';
            app.DnominateurEditField_2Label = uilabel(app.UT1Tab);
            app.DnominateurEditField_2Label.Text = 'Dénominateur ';
            app.T1_Dnum = uieditfield(app.UT1Tab, 'text');
            app.T1_Dnum.Value = '[90.09 1]';
            app.T1T2Tab = uitab(app.TabGroup);
            app.T1T2Tab.Title = 'T1->T2';
            app.NumrateurEditFieldLabel = uilabel(app.T1T2Tab);
            app.NumrateurEditFieldLabel.Text = 'Numérateur ';
            app.T2_Num = uieditfield(app.T1T2Tab, 'text');
            app.T2_Num.Value = '[0.789]';
            app.DnominateurEditFieldLabel = uilabel(app.T1T2Tab);
            app.DnominateurEditFieldLabel.Text = 'Dénominateur';
            app.T2_Dnum = uieditfield(app.T1T2Tab, 'text');
            app.T2_Dnum.Value = '[40.49 1]';
            app.T2T3Tab = uitab(app.TabGroup);
            app.T2T3Tab.Title = 'T2->T3';
            app.NumrateurEditField_3Label = uilabel(app.T2T3Tab);
            app.NumrateurEditField_3Label.Text = 'Numérateur ';
            app.T3_Num = uieditfield(app.T2T3Tab, 'text');
            app.T3_Num.Value = '[0.921]';
            app.DnominateurEditField_3Label = uilabel(app.T2T3Tab);
            app.DnominateurEditField_3Label.Text = 'Dénominateur ';
            app.T3_Dnum = uieditfield(app.T2T3Tab, 'text');
            app.T3_Dnum.Value = '[20.28 1]';
            app.PerturbationTab = uitab(app.TabGroup2);
            app.PerturbationTab.Title = 'Perturbation';
            app.graph_perturbation = uiaxes(app.PerturbationTab);
            title(app.graph_perturbation, 'Perturbation')
            xlabel(app.graph_perturbation, 'Temps (s)')
            ylabel(app.graph_perturbation, 'Puissance (W)')
            zlabel(app.graph_perturbation, 'Z')
            app.TabGroup_2 = uitabgroup(app.PerturbationTab);
            app.PT1Tab = uitab(app.TabGroup_2);
            app.PT1Tab.Title = 'P->T1';
            app.NumrateurEditField_4Label = uilabel(app.PT1Tab);
            app.NumrateurEditField_4Label.Text = 'Numérateur ';
            app.Num_P1 = uieditfield(app.PT1Tab, 'text');
            app.Num_P1.Value = '[3.5001]';
            app.DnominateurEditField_4Label = uilabel(app.PT1Tab);
            app.DnominateurEditField_4Label.Text = 'Dénominateur ';
            app.Dnom_P1 = uieditfield(app.PT1Tab, 'text');
            app.Dnom_P1.Value = '[89.311 1]';
            app.PT2Tab = uitab(app.TabGroup_2);
            app.PT2Tab.Title = 'P->T2';
            app.NumrateurEditField_5Label = uilabel(app.PT2Tab);
            app.NumrateurEditField_5Label.Text = 'Numérateur ';
            app.Num_P2 = uieditfield(app.PT2Tab, 'text');
            app.Num_P2.Value = '[3.6616]';
            app.DnominateurEditField_5Label = uilabel(app.PT2Tab);
            app.DnominateurEditField_5Label.Text = 'Dénominateur';
            app.Dnom_P2 = uieditfield(app.PT2Tab, 'text');
            app.Dnom_P2.Value = '[115.9 1]';
            app.PT3Tab = uitab(app.TabGroup_2);
            app.PT3Tab.Title = 'P->T3';
            app.NumrateurEditField_6Label = uilabel(app.PT3Tab);
            app.NumrateurEditField_6Label.Text = 'Numérateur ';
            app.Num_P3 = uieditfield(app.PT3Tab, 'text');
            app.Num_P3.Value = '[3.6777]';
            app.DnominateurEditField_6Label = uilabel(app.PT3Tab);
            app.DnominateurEditField_6Label.Text = 'Dénominateur ';
            app.Dnom_P3 = uieditfield(app.PT3Tab, 'text');
            app.Dnom_P3.Value = '[195.52 1]';
            app.RsistancesTab = uitab(app.TabGroup2);
            app.RsistancesTab.Title = 'Résistances';
            app.graph_res = uiaxes(app.RsistancesTab);
            title(app.graph_res, 'Réponse du système')
            xlabel(app.graph_res, 'Temps (s)')
            ylabel(app.graph_res, 'Résistance (Ohm)')
            zlabel(app.graph_res, 'Z')
            app.ButtonGroup_3 = uibuttongroup(app.RsistancesTab);
            app.BEditFieldLabel = uilabel(app.ButtonGroup_3);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.ButtonGroup_3, 'numeric');
            app.BEditField.Value = 4798.842;
            app.AEditFieldLabel = uilabel(app.ButtonGroup_3);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.ButtonGroup_3, 'numeric');
            app.AEditField.Value = -14.65719769;
            app.CEditFieldLabel = uilabel(app.ButtonGroup_3);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.ButtonGroup_3, 'numeric');
            app.CEditField.Value = -115334;
            app.ThermistancesLabel_2 = uilabel(app.ButtonGroup_3);
            app.ThermistancesLabel_2.Text = 'Thermistances';
            app.DEditFieldLabel = uilabel(app.ButtonGroup_3);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.ButtonGroup_3, 'numeric');
            app.DEditField.Value = -3730535;
            app.R25EditFieldLabel = uilabel(app.ButtonGroup_3);
            app.R25EditFieldLabel.Text = 'R25';
            app.R25EditField = uieditfield(app.ButtonGroup_3, 'numeric');
            app.R25EditField.Value = 10000;
            app.TensionsTab = uitab(app.TabGroup2);
            app.TensionsTab.Title = 'Tensions';
            app.graph_tens = uiaxes(app.TensionsTab);
            title(app.graph_tens, 'Réponse du système')
            xlabel(app.graph_tens, 'Temps (s)')
            ylabel(app.graph_tens, 'Tension (V)')
            zlabel(app.graph_tens, 'Z')
            app.RgulateurTab = uitab(app.TabGroup2);
            app.RgulateurTab.Title = 'Régulateur';
            app.graph_reg = uiaxes(app.RgulateurTab);
            title(app.graph_reg, 'Régulateur')
            xlabel(app.graph_reg, 'Temps (s)')
            ylabel(app.graph_reg, 'Tension (V)')
            zlabel(app.graph_reg, 'Z')
            app.ADCbitsEditField_2Label = uilabel(app.RgulateurTab);
            app.ADCbitsEditField_2Label.Text = 'ADC (bits)';
            app.ADCbitsEditField_2 = uieditfield(app.RgulateurTab, 'numeric');
            app.ADCbitsEditField_2.ValueChangedFcn = createCallbackFcn(app, @ADCbitsEditField_2ValueChanged, true);
            app.ADCbitsEditField_2.Value = 10;
            app.PWMTab = uitab(app.TabGroup2);
            app.PWMTab.Title = 'PWM';
            app.graph_redresseur_pwm = uiaxes(app.PWMTab);
            title(app.graph_redresseur_pwm, 'Redresseur PWM')
            xlabel(app.graph_redresseur_pwm, 'Temps (s)')
            ylabel(app.graph_redresseur_pwm, 'Tension (V)')
            zlabel(app.graph_redresseur_pwm, 'Z')
            app.graph_entre_pwm = uiaxes(app.PWMTab);
            title(app.graph_entre_pwm, 'Entrée PWM')
            xlabel(app.graph_entre_pwm, 'Temps (s)')
            ylabel(app.graph_entre_pwm, 'Rapport cyclique')
            zlabel(app.graph_entre_pwm, 'Z')
            app.DACbitsEditField_2Label = uilabel(app.PWMTab);
            app.DACbitsEditField_2Label.Text = 'DAC (bits)';
            app.DACbitsEditField_2 = uieditfield(app.PWMTab, 'numeric');
            app.DACbitsEditField_2.ValueChangedFcn = createCallbackFcn(app, @DACbitsEditField_2ValueChanged, true);
            app.DACbitsEditField_2.Value = 12;
            app.AmpliTab = uitab(app.TabGroup2);
            app.AmpliTab.Title = 'Ampli';
            app.graph_ampli = uiaxes(app.AmpliTab);
            title(app.graph_ampli, 'Source du TEC')
            xlabel(app.graph_ampli, 'Temps (s)')
            ylabel(app.graph_ampli, 'Courant (A)')
            zlabel(app.graph_ampli, 'Z')
            app.IdentificationTab = uitab(app.TabGroup2);
            app.IdentificationTab.Title = 'Identification';
            app.graph_identification = uiaxes(app.IdentificationTab);
            title(app.graph_identification, 'Réponse du système')
            xlabel(app.graph_identification, 'Temps (s)')
            ylabel(app.graph_identification, 'Température (°C)')
            zlabel(app.graph_identification, 'Z')
            app.graph_id_entre = uiaxes(app.IdentificationTab);
            title(app.graph_id_entre, 'Entrées du système')
            xlabel(app.graph_id_entre, 'Temps (s)')
            ylabel(app.graph_id_entre, 'Entrées')
            zlabel(app.graph_id_entre, 'Z')
            app.ChoisirunfichierdchelonButton = uibutton(app.IdentificationTab, 'push');
            app.ChoisirunfichierdchelonButton.ButtonPushedFcn = createCallbackFcn(app, @ChoisirunfichierdchelonButtonPushed, true);
            app.ChoisirunfichierdchelonButton.Text = 'Choisir un fichier d''échelon';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.BouclefermeButton = uiradiobutton(app.ButtonGroup);
            app.BouclefermeButton.Text = 'Boucle fermée';
            app.BouclefermeButton.Value = true;
            app.BoucleouverteButton = uiradiobutton(app.ButtonGroup);
            app.BoucleouverteButton.Text = 'Boucle ouverte';
            app.RguldbutsEditFieldLabel = uilabel(app.ButtonGroup);
            app.RguldbutsEditFieldLabel.Text = 'Régul début (s)';
            app.RguldbutsEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.RguldbutsEditField.Value = 500;
            app.PuissanceEditFieldLabel = uilabel(app.ButtonGroup);
            app.PuissanceEditFieldLabel.Text = 'Puissance (%)';
            app.PuissanceEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.ConsigneCLabel = uilabel(app.ButtonGroup);
            app.ConsigneCLabel.Text = 'Consigne (°C)';
            app.ConsigneCEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.ConsigneCEditField.Value = 30;
            app.PEditFieldLabel = uilabel(app.ButtonGroup);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.PEditField.Value = 0.53;
            app.IEditFieldLabel = uilabel(app.ButtonGroup);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.IEditField.Value = 0.0049;
            app.RgulateurLabel = uilabel(app.ButtonGroup);
            app.RgulateurLabel.Text = 'Régulateur';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.DbutsEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.DbutsEditFieldLabel.Text = 'Début (s)';
            app.DbutsEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.DbutsEditField.Value = 600;
            app.PuissanceWEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.PuissanceWEditFieldLabel.Text = 'Puissance (W)';
            app.PuissanceWEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.FinsEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.FinsEditFieldLabel.Text = 'Fin (s)';
            app.FinsEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.FinsEditField.Value = 2000;
            app.PerturbationLabel = uilabel(app.ButtonGroup_2);
            app.PerturbationLabel.Text = 'Perturbation';
            app.ButtonGroup_4 = uibuttongroup(app.UIFigure);
            app.chantillonnageHzEditFieldLabel = uilabel(app.ButtonGroup_4);
            app.chantillonnageHzEditFieldLabel.Text = 'Échantillonnage (Hz)';
            app.chantillonnageHzEditField = uieditfield(app.ButtonGroup_4, 'numeric');
            app.chantillonnageHzEditField.Value = 1;
            app.lectroniqueLabel = uilabel(app.ButtonGroup_4);
            app.lectroniqueLabel.Text = 'Électronique';
            app.Panel_2 = uipanel(app.ButtonGroup_4);
            app.GainT1EditFieldLabel = uilabel(app.Panel_2);
            app.GainT1EditFieldLabel.Text = 'Gain T1:';
            app.GainT1EditField = uieditfield(app.Panel_2, 'numeric');
            app.GainT1EditField.Value = 1.488;
            app.DcalT1EditFieldLabel = uilabel(app.Panel_2);
            app.DcalT1EditFieldLabel.Text = 'Décal. T1:';
            app.DcalT1EditField = uieditfield(app.Panel_2, 'numeric');
            app.DcalT1EditField.Value = 1.22;
            app.GainT2EditFieldLabel = uilabel(app.Panel_2);
            app.GainT2EditFieldLabel.Text = 'Gain T2:';
            app.GainT2EditField = uieditfield(app.Panel_2, 'numeric');
            app.GainT2EditField.Value = 1.488;
            app.DcalT2EditFieldLabel = uilabel(app.Panel_2);
            app.DcalT2EditFieldLabel.Text = 'Décal. T2:';
            app.DcalT2EditField = uieditfield(app.Panel_2, 'numeric');
            app.DcalT2EditField.Value = 1.22;
            app.GainT3EditFieldLabel = uilabel(app.Panel_2);
            app.GainT3EditFieldLabel.Text = 'Gain T3:';
            app.GainT3EditField = uieditfield(app.Panel_2, 'numeric');
            app.GainT3EditField.Value = 1.488;
            app.DcalT3EditFieldLabel = uilabel(app.Panel_2);
            app.DcalT3EditFieldLabel.Text = 'Décal. T3:';
            app.DcalT3EditField = uieditfield(app.Panel_2, 'numeric');
            app.DcalT3EditField.Value = 1.22;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end