classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Den3FieldEditField_2       matlab.ui.control.EditField
        Den3FieldEditField_2Label  matlab.ui.control.Label
        Num3FieldEditField_2       matlab.ui.control.EditField
        Num3FieldEditField_2Label  matlab.ui.control.Label
        Den2FieldEditField_2       matlab.ui.control.EditField
        Den2FieldEditField_2Label  matlab.ui.control.Label
        Num2FieldEditField_2       matlab.ui.control.EditField
        Num2FieldEditField_2Label  matlab.ui.control.Label
        Den1FieldEditField_2       matlab.ui.control.EditField
        Den1FieldEditField_2Label  matlab.ui.control.Label
        Num1FieldEditField_2       matlab.ui.control.EditField
        Num1FieldEditField_2Label  matlab.ui.control.Label
        ValideretlancerButton      matlab.ui.control.Button
        IEditField                 matlab.ui.control.NumericEditField
        IEditFieldLabel            matlab.ui.control.Label
        PEditField                 matlab.ui.control.NumericEditField
        PEditFieldLabel            matlab.ui.control.Label
        ConsigneEditField          matlab.ui.control.NumericEditField
        ConsigneEditFieldLabel     matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes, UIFigure
        function UIFigureButtonDown(app, event)
        
        %end

        %function ValideretlancerButtonPushed(app, event)
    
         %   end


        end

        % Button pushed function: ValideretlancerButton
        function ValideretlancerButtonPushed(app, event)
            % Lire les valeurs
                P = app.PEditField.Value;
                I = app.IEditField.Value;
                Consigne = app.ConsigneEditField.Value;

    % Lire les TF
                num1 = str2num(app.Num1FieldEditField_2.Value);
                den1 = str2num(app.Den1FieldEditField_2.Value);
                num2 = str2num(app.Num2FieldEditField_2.Value);
                den2 = str2num(app.Den2FieldEditField_2.Value);
                num3 = str2num(app.Num3FieldEditField_2.Value);
                den3 = str2num(app.Den3FieldEditField_2.Value);

    % Vérification
                if isnan(P) || isnan(I) || isnan(Consigne)
                    uialert(app.UIFigure, 'Les valeurs entrées ne sont pas valides', 'Erreur');
                    return;
                end

    % Mettre à jour Simulink
                assignin('base', 'P', P);
                assignin('base', 'I', I);
                assignin('base', 'Consigne', Consigne);

                try
                    set_param("Design2simulink/PID Controller", 'P', num2str(P), 'I', num2str(I));
                    set_param('Design2simulink/Step', 'After', num2str(Consigne));
                    %uialert(app.UIFigure, ['hello : ' [num2str(num1)]], 'Erreur');
                    set_param('Design2simulink/Procédé/Plaque/T1', 'Numerator', mat2str(num1), 'Denominator', mat2str(den1));
                    set_param('Design2simulink/Procédé/Plaque/T2', 'Numerator', mat2str(num2), 'Denominator', mat2str(den2));
                    set_param('Design2simulink/Procédé/Plaque/T3', 'Numerator', mat2str(num3), 'Denominator', mat2str(den3));

                catch ME

                    uialert(app.UIFigure, ['Erreur dans Simulink : ' ME.message], 'Erreur');
                    return;
                end

    % Lancer la simulation
                simOut = sim('Design2simulink');
                signal_data = simOut.get('signal_sortie');

                if isempty(signal_data)
                    uialert(app.UIFigure, 'Aucune donnée de simulation disponible', 'Erreur');
                    return;
                end

    % Tracer
                temps = signal_data.time;
                valeurs = signal_data.signals.values;
                plot(app.UIAxes, temps, valeurs, 'LineWidth', 1.5);
                title(app.UIAxes, 'Réponse du système');
                xlabel(app.UIAxes, 'Temps (s)');
                ylabel(app.UIAxes, 'Température');
                grid(app.UIAxes, 'on');

                uialert(app.UIFigure, 'Paramètres mis à jour avec succès', 'Succès');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.ConsigneEditFieldLabel = uilabel(app.UIFigure);
            app.ConsigneEditFieldLabel.Text = 'Consigne';
            app.ConsigneEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConsigneEditField.Value = 28;
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditField.Value = 0.5;
            app.IEditFieldLabel = uilabel(app.UIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.UIFigure, 'numeric');
            app.IEditField.Value = 0.005;
            app.ValideretlancerButton = uibutton(app.UIFigure, 'push');
            app.ValideretlancerButton.ButtonPushedFcn = createCallbackFcn(app, @ValideretlancerButtonPushed, true);
            app.ValideretlancerButton.Text = 'Valider et lancer';
            app.Num1FieldEditField_2Label = uilabel(app.UIFigure);
            app.Num1FieldEditField_2Label.Text = 'Num1Field';
            app.Num1FieldEditField_2 = uieditfield(app.UIFigure, 'text');
            app.Den1FieldEditField_2Label = uilabel(app.UIFigure);
            app.Den1FieldEditField_2Label.Text = 'Den1Field';
            app.Den1FieldEditField_2 = uieditfield(app.UIFigure, 'text');
            app.Num2FieldEditField_2Label = uilabel(app.UIFigure);
            app.Num2FieldEditField_2Label.Text = 'Num2Field';
            app.Num2FieldEditField_2 = uieditfield(app.UIFigure, 'text');
            app.Den2FieldEditField_2Label = uilabel(app.UIFigure);
            app.Den2FieldEditField_2Label.Text = 'Den2Field';
            app.Den2FieldEditField_2 = uieditfield(app.UIFigure, 'text');
            app.Num3FieldEditField_2Label = uilabel(app.UIFigure);
            app.Num3FieldEditField_2Label.Text = 'Num3Field';
            app.Num3FieldEditField_2 = uieditfield(app.UIFigure, 'text');
            app.Den3FieldEditField_2Label = uilabel(app.UIFigure);
            app.Den3FieldEditField_2Label.Text = 'Den3Field';
            app.Den3FieldEditField_2 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end